classdef Musicalpitchgenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        Tab_fm                matlab.ui.container.Tab
        UIAxes_FftResult      matlab.ui.control.UIAxes
        UIAxes_FftM           matlab.ui.control.UIAxes
        UIAxes_FftC           matlab.ui.control.UIAxes
        UIAxes_Result         matlab.ui.control.UIAxes
        UIAxes_TM             matlab.ui.control.UIAxes
        UIAxes_TC             matlab.ui.control.UIAxes
        Panel_carrier         matlab.ui.container.Panel
        AcSliderLabel         matlab.ui.control.Label
        Slider_carrier_amp    matlab.ui.control.Slider
        FcKnobLabel           matlab.ui.control.Label
        Knob_carrier_freq     matlab.ui.control.Knob
        DropDown_2Label       matlab.ui.control.Label
        DropDown_carrier_mul  matlab.ui.control.DropDown
        Panel_mod             matlab.ui.container.Panel
        Slider_mod_amp        matlab.ui.control.Slider
        DLabel                matlab.ui.control.Label
        FmKnob_2Label         matlab.ui.control.Label
        Knob_mod_freq         matlab.ui.control.Knob
        Label                 matlab.ui.control.Label
        DropDown_mod_mul      matlab.ui.control.DropDown
        Button_play_fmtab     matlab.ui.control.Button
        Title                 matlab.ui.control.Label
    end

    
    properties (Access = public)
        carrier_amp_prev;
            carrier_freq_prev;
            mod_amp_prev;
            mod_freq_prev;

            carrier_amp;                         %A
            carrier_freq;  %C
            mod_amp;                                   %D
            mod_freq; 

            
    end


    methods (Access = public)
        
        function plotFnc(app,A,C,D,M)
            T=0.000001;
            Fs=1/T;
            t = 0:T:.05;  %seconds
            
             y=A*sin(2*pi*C*t+D*sin(2*pi*M*t));
            c=A*sin(2*pi*C*t);
            m=D*sin(2*pi*M*t);
            plot(app.UIAxes_TC,t,c);

            l=length(c);
            Y=fft(c);
            P2 = abs(Y/l);
            P1 = P2(1:(l/2+1));
            P1(2:end-1) = 2*P1(2:end-1);
            f = Fs*(0:(l/2))/l;
            stem(app.UIAxes_FftC,f,P1);
            app.UIAxes_FftC.XLim = [0 max(2*C,100)];

            
            plot(app.UIAxes_TM,t,m);
    
            l=length(m);
            Y=fft(m);
            P2 = abs(Y/l);
            P1 = P2(1:l/2+1);
            P1(2:end-1) = 2*P1(2:end-1);
            f = Fs*(0:(l/2))/l;
            stem(app.UIAxes_FftM,f,P1);
            app.UIAxes_FftM.XLim = [0 max(2*M,100)];

            
            plot(app.UIAxes_Result,t,y);
       
    
            l=length(y);
            Y=fft(y);
            P2 = abs(Y/l);
            P1 = P2(1:l/2+1);
            P1(2:end-1) = 2*P1(2:end-1);
            f = Fs*(0:(l/2))/l;
          
            stem(app.UIAxes_FftResult,f,P1);
            app.UIAxes_FftResult.XLim = [0 max(max(2*C,2*M),100)];
         
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           %A=2;
            %C = 210;
            %D = 10;
            %M = 70;
%plotFnc(app,A,C,D,M);
        end

        % Button pushed function: Button_play_fmtab
        function Button_play_fmtabPushed(app, event)
           
        
         T=0.00001;
        Fs=1/T;
        t = 0:T:1;
        %Fs = 44100;
          %  T = 1/Fs;
           % dur = 1.0;
            %t = 0:T:dur; 
        A = app.Slider_carrier_amp.Value;                         %A
            C=app.Knob_carrier_freq.Value*app.DropDown_carrier_mul.Value;  %C
            D=app.Slider_mod_amp.Value;                                   %D
            M= app.Knob_mod_freq.Value*app.DropDown_mod_mul.Value; 


         y=A*sin(2*pi*C*t+D*sin(2*pi*M*t));
        sound(y,Fs);


        end

        % Value changed function: DropDown_carrier_mul, 
        % DropDown_mod_mul, Knob_carrier_freq, Knob_mod_freq, 
        % Slider_carrier_amp, Slider_mod_amp
        function valueChanged(app, event)
            A = app.Slider_carrier_amp.Value;                         %A
            C=app.Knob_carrier_freq.Value*app.DropDown_carrier_mul.Value;  %C
            D=app.Slider_mod_amp.Value;                                   %D
            M= app.Knob_mod_freq.Value*app.DropDown_mod_mul.Value; 
           
            plotFnc(app,A,C,D,M);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_fm = uitab(app.TabGroup);
            app.Tab_fm.Title = 'Frequency Modulation Synthesis';
            app.UIAxes_FftResult = uiaxes(app.Tab_fm);
            title(app.UIAxes_FftResult, 'FFT')
            xlabel(app.UIAxes_FftResult, 'Frequency')
            ylabel(app.UIAxes_FftResult, 'Amplitude')
            zlabel(app.UIAxes_FftResult, 'Z')
            app.UIAxes_FftM = uiaxes(app.Tab_fm);
            title(app.UIAxes_FftM, 'FFT')
            xlabel(app.UIAxes_FftM, 'Frequency')
            ylabel(app.UIAxes_FftM, 'Amplitude')
            zlabel(app.UIAxes_FftM, 'Z')
            app.UIAxes_FftC = uiaxes(app.Tab_fm);
            title(app.UIAxes_FftC, 'FFT')
            xlabel(app.UIAxes_FftC, 'Frequency')
            ylabel(app.UIAxes_FftC, 'Amplitude')
            zlabel(app.UIAxes_FftC, 'Z')
            app.UIAxes_Result = uiaxes(app.Tab_fm);
            title(app.UIAxes_Result, 'Output Signal')
            xlabel(app.UIAxes_Result, 'Time')
            ylabel(app.UIAxes_Result, 'Amplitude')
            zlabel(app.UIAxes_Result, 'Z')
            app.UIAxes_TM = uiaxes(app.Tab_fm);
            title(app.UIAxes_TM, 'Signal 2')
            xlabel(app.UIAxes_TM, 'Time')
            ylabel(app.UIAxes_TM, 'Amplitude')
            zlabel(app.UIAxes_TM, 'Z')
            app.UIAxes_TC = uiaxes(app.Tab_fm);
            title(app.UIAxes_TC, 'Signal 1')
            xlabel(app.UIAxes_TC, 'Time')
            ylabel(app.UIAxes_TC, 'Amplitude')
            zlabel(app.UIAxes_TC, 'Z')
            app.Panel_carrier = uipanel(app.Tab_fm);
            app.Panel_carrier.Title = 'SIGNAL 1';
            app.AcSliderLabel = uilabel(app.Panel_carrier);
            app.AcSliderLabel.Text = 'Ac';
            app.Slider_carrier_amp = uislider(app.Panel_carrier);
            app.Slider_carrier_amp.Limits = [0 10];
            app.Slider_carrier_amp.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.FcKnobLabel = uilabel(app.Panel_carrier);
            app.FcKnobLabel.Text = 'Fc';
            app.Knob_carrier_freq = uiknob(app.Panel_carrier, 'continuous');
            app.Knob_carrier_freq.Limits = [0 10];
            app.Knob_carrier_freq.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.DropDown_2Label = uilabel(app.Panel_carrier);
            app.DropDown_2Label.Text = '*';
            app.DropDown_carrier_mul = uidropdown(app.Panel_carrier);
            app.DropDown_carrier_mul.Items = {'10', '100', '1000'};
            app.DropDown_carrier_mul.ItemsData = [10 100 1000];
            app.DropDown_carrier_mul.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.DropDown_carrier_mul.Value = 10;
            app.Panel_mod = uipanel(app.Tab_fm);
            app.Panel_mod.Title = 'SIGNAL 2';
            app.Slider_mod_amp = uislider(app.Panel_mod);
            app.Slider_mod_amp.Limits = [0 10];
            app.Slider_mod_amp.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.DLabel = uilabel(app.Panel_mod);
            app.DLabel.Text = 'D';
            app.FmKnob_2Label = uilabel(app.Panel_mod);
            app.FmKnob_2Label.Text = 'Fm';
            app.Knob_mod_freq = uiknob(app.Panel_mod, 'continuous');
            app.Knob_mod_freq.Limits = [0 10];
            app.Knob_mod_freq.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.Label = uilabel(app.Panel_mod);
            app.Label.Text = '*';
            app.DropDown_mod_mul = uidropdown(app.Panel_mod);
            app.DropDown_mod_mul.Items = {'10', '100', '1000'};
            app.DropDown_mod_mul.ItemsData = [10 100 1000];
            app.DropDown_mod_mul.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.DropDown_mod_mul.Value = 10;
            app.Button_play_fmtab = uibutton(app.Tab_fm, 'push');
            app.Button_play_fmtab.ButtonPushedFcn = createCallbackFcn(app, @Button_play_fmtabPushed, true);
            app.Button_play_fmtab.Text = 'Play ▶️';
            app.Title = uilabel(app.Tab_fm);
            app.Title.Text = 'MUSICAL PITCH GENERATOR';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Musicalpitchgenerator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end